classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SelectModeDropDown       matlab.ui.control.DropDown
        SelectModeLabel          matlab.ui.control.Label
        StartLocationLabel_2     matlab.ui.control.Label
        StartLocationTextArea_4  matlab.ui.control.TextArea
        Label_4                  matlab.ui.control.Label
        StartLocationTextArea_3  matlab.ui.control.TextArea
        Label_3                  matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        StartLocationTextArea_2  matlab.ui.control.TextArea
        Label                    matlab.ui.control.Label
        StartLocationTextArea    matlab.ui.control.TextArea
        StartLocationLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        TopologicalApp  % Topological Path Planning app 
        MetricApp       % Metric Path Planning app
        LocalizationApp % Localization app
        MappingApp      % Mapping app
        
        MainApp         % Main app object
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;
        end

        % Value changed function: SelectModeDropDown
        function SelectModeDropDownValueChanged(app, event)
            mode = app.SelectModeDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartLocationLabel = uilabel(app.UIFigure);
            app.StartLocationLabel.Visible = 'off';
            app.StartLocationLabel.Text = 'Start Location: ( ';
            app.StartLocationTextArea = uitextarea(app.UIFigure);
            app.StartLocationTextArea.Tag = 'xStart';
            app.StartLocationTextArea.Visible = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = ', ';
            app.StartLocationTextArea_2 = uitextarea(app.UIFigure);
            app.StartLocationTextArea_2.Tag = 'xStart';
            app.StartLocationTextArea_2.Visible = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = ')';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = ')';
            app.StartLocationTextArea_3 = uitextarea(app.UIFigure);
            app.StartLocationTextArea_3.Tag = 'xStart';
            app.StartLocationTextArea_3.Visible = 'off';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = ', ';
            app.StartLocationTextArea_4 = uitextarea(app.UIFigure);
            app.StartLocationTextArea_4.Tag = 'xStart';
            app.StartLocationTextArea_4.Visible = 'off';
            app.StartLocationLabel_2 = uilabel(app.UIFigure);
            app.StartLocationLabel_2.Visible = 'off';
            app.StartLocationLabel_2.Text = 'Start Location: ( ';
            app.SelectModeLabel = uilabel(app.UIFigure);
            app.SelectModeLabel.Text = 'Select Mode';
            app.SelectModeDropDown = uidropdown(app.UIFigure);
            app.SelectModeDropDown.Items = {'Main', 'Topological Path', 'Metric Path', 'Localization', 'Mapping'};
            app.SelectModeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectModeDropDownValueChanged, true);
            app.SelectModeDropDown.Value = 'Main';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end